classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DimensionamentoTab              matlab.ui.container.Tab
        SistemasdeMedidasButtonGroup    matlab.ui.container.ButtonGroup
        SistemaInternacionalButton      matlab.ui.control.RadioButton
        SistemaImperialButton           matlab.ui.control.RadioButton
        PropriedadesdomaterialPanel     matlab.ui.container.Panel
        ResistnciaaoescoamentoMPaEditFieldLabel  matlab.ui.control.Label
        ResistnciaaoescoamentoMPaEditField  matlab.ui.control.NumericEditField
        ResistnciatraoMPaEditFieldLabel  matlab.ui.control.Label
        ResistnciatraoMPaEditField      matlab.ui.control.NumericEditField
        CoefAtritoEditFieldLabel        matlab.ui.control.Label
        CoefAtritoEditField             matlab.ui.control.NumericEditField
        ColarPanel                      matlab.ui.container.Panel
        DimetrommEditFieldLabel         matlab.ui.control.Label
        DimetrommEditField              matlab.ui.control.NumericEditField
        CoefAtritoEditField_2Label      matlab.ui.control.Label
        CoefAtritoEditField_2           matlab.ui.control.NumericEditField
        CargaseDinmicaPanel             matlab.ui.container.Panel
        CargaNEditFieldLabel            matlab.ui.control.Label
        CargaNEditField                 matlab.ui.control.NumericEditField
        VelocidademmsEditFieldLabel     matlab.ui.control.Label
        VelocidademmsEditField          matlab.ui.control.NumericEditField
        PerfilRoscaButtonGroup          matlab.ui.container.ButtonGroup
        QuadradaButton                  matlab.ui.control.RadioButton
        AcmeButton                      matlab.ui.control.RadioButton
        nguloEditFieldLabel             matlab.ui.control.Label
        nguloEditField                  matlab.ui.control.NumericEditField
        INFOPERFISButton                matlab.ui.control.Button
        TipodeParafusoPanel             matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NormalizadoButton               matlab.ui.control.RadioButton
        PersonalizadoButton             matlab.ui.control.RadioButton
        RoscaDropDownLabel              matlab.ui.control.Label
        RoscaDropDown                   matlab.ui.control.DropDown
        EntradasEditFieldLabel          matlab.ui.control.Label
        EntradasEditField               matlab.ui.control.NumericEditField
        PadroDropDownLabel              matlab.ui.control.Label
        PadroDropDown                   matlab.ui.control.DropDown
        EntradasEditField_2Label        matlab.ui.control.Label
        EntradasEditField_2             matlab.ui.control.NumericEditField
        PassommEditFieldLabel           matlab.ui.control.Label
        PassommEditField                matlab.ui.control.NumericEditField
        DimetroExternommEditFieldLabel  matlab.ui.control.Label
        DimetroExternommEditField       matlab.ui.control.NumericEditField
        PassoinEditFieldLabel           matlab.ui.control.Label
        PassoinEditField                matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        CrditosButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        resultsApp % pop up que abre com os resultados
        
        units = 'SI'; % tipo de sistema usado
        screw_type = 'normalizado'; % se é normalizado ou personalizado
        
        %Se for normalizado
        std_screw = 'M3';% se for normalizado, qual o tipo [M3, M7...]
        std_screw_vector
        
        rosca = 'Passo Fino';%
        entradas = 0; %
        
        %Se for personalizado
        d_e = 0; % diametro externo
        passo = 0;
        
        travante = 1; % 1 = Sim, 0 = Não
        
        carga = 0;
        velocidade = 0;
        
        coef_atrito = 0;
        sy = 0;
        sut = 0;
        
        coef_atrito_colar = 0; %coef de atrito do colar
        d_c = 0; % diametro do colar
        
        type_roca = "Quadrada";
        angulo_acme = 0;
        
        
        criterio_tau= 0;
        criterio_sigma= 0;
        criterio_sigma_b= 0;
        criterio_sigma_fle= 0;
        criterio_cisalhamento_rosca = 0;
        
        PADROES_SI = containers.Map({'M3','M3.5','M4','M5', 'M6', 'M7', 'M8', 'M10', 'M13', 'M14', 'M16', 'M18',...
            'M20', 'M22', 'M24', 'M27', 'M30', 'M33', 'M36', 'M39'},{
            [3.0 0.5 2.39 5.03],...
            [3.5 0.6 2.76 6.78],...
            [4.0 0.7 3.14 8.78],...
            [5.0 0.8 4.02 14.2],...
            [6.0 1.0 4.77 20.1],...
            [7.0 1.0 5.77 28.9],...
            [8.0 1.25 6.47 36.6 1.0 6.77 39.2],...
            [10.0 1.5 8.16 58.0 1.25 8.47 61.2],...
            [12.0 1.75 9.85 84.3 1.25 10.5 92.1],...
            [14.0 2.0 11.6 115.0 1.5 12.2 125.0],...
            [16.0 2.0 13.6 157.0 1.5 14.2 167.0],...
            [18.0 2.5 14.9 192.0 1.5 16.2 216.0],...
            [20.0 2.5 16.9 245.0 1.5 18.2 272.0],...
            [22.0 2.5 18.9 303.0 1.5 20.2 333.0],...
            [24.0 3.0 20.3 353.0 2.0 21.6 384.0],...
            [27.0 3.0 23.3 459.0 2.0 24.6 496.0],...
            [30.0 3.5 25.7 561.0 2.0 27.6 621.0],...
            [33.0 3.5 28.7 694.0 2.0 30.6 761.0],...
            [36.0 4.0 31.1 817.0 3.0 32.3 865.0],...
            [39.0 4.0 34.1 976.0 3.0 35.3 1030.0]});
        
        PADROES_IS = containers.Map({'0','1','2','3', '4', '5', '6', '8', '10', '12', '1/4', '5/16',...
            '3/8', '7/16', '1/2', '9/16', '5/8', '3/4', '7/8'},{[0.06, 0.0, 0.00151],...
            [0.073, 0.00218, 0.00237],...
            [0.086, 0.0031, 0.00339],...
            [0.099, 0.00406, 0.00451],...
            [0.112, 0.00496, 0.00566],...
            [0.125, 0.00672, 0.00716],...
            [0.138, 0.00745, 0.00874],...
            [0.164, 0.01196, 0.01285],...
            [0.19, 0.0145, 0.0175],...
            [0.216, 0.0206, 0.0226],...
            [0.25, 0.0269, 0.0326],...
            [0.3125, 0.0454, 0.0524],...
            [0.375, 0.067, 0.0809],...
            [0.4375, 0.0933, 0.109],...
            [0.5, 0.1257, 0.1486],...
            [0.5625, 0.162, 0.189],...
            [0.625, 0.202, 0.24],...
            [0.75, 0.302, 0.351],...
            [0.875, 0.419, 0.4]});
        
    end
    
    methods (Access = private)
        
    end

    methods (Access = private)

        % Button pushed function: CrditosButton
        function CrditosButtonPushed(app, event)
            msgbox({'Aluno: Alessandro Melo de Oliveira - NºUSP: 10788662';'Elementos de Máquina SEM0326 - 2021.2'}, 'Créditos');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'Normalizado') == 1
                app.screw_type = 'normalizado';
                app.EntradasEditFieldLabel.Visible = 'on';
                app.EntradasEditField.Visible= 'on';
                app.PadroDropDownLabel.Visible= 'on';
                app.PadroDropDown.Visible= 'on';
                app.RoscaDropDown.Visible= 'on';
                app.RoscaDropDownLabel.Visible= 'on';
                
                app.DimetroExternommEditField.Visible = 'off';
                app.DimetroExternommEditFieldLabel.Visible = 'off';
                app.PassommEditField.Visible = 'off';
                app.PassommEditFieldLabel.Visible = 'off';
                app.EntradasEditField_2.Visible = 'off';
                app.EntradasEditField_2Label.Visible = 'off';
                
                if strcmp(app.units, 'IS') == 1
                    app.PassoinEditFieldLabel.Visible = 'on';
                    app.PassoinEditField.Visible = 'on';
                else
                    app.PassoinEditFieldLabel.Visible = 'off';
                    app.PassoinEditField.Visible = 'off';
                end

            elseif strcmp(selectedButton.Text, 'Personalizado') == 1
                app.screw_type = 'personalizado';
                app.EntradasEditFieldLabel.Visible = 'off';
                app.EntradasEditField.Visible= 'off';
                app.PadroDropDownLabel.Visible= 'off';
                app.PadroDropDown.Visible= 'off';
                app.RoscaDropDown.Visible= 'off';
                app.RoscaDropDownLabel.Visible= 'off';
                
                app.PassoinEditFieldLabel.Visible = 'off';
                app.PassoinEditField.Visible = 'off';
                
                app.DimetroExternommEditField.Visible = 'on';
                app.DimetroExternommEditFieldLabel.Visible = 'on';
                app.PassommEditField.Visible = 'on';
                app.PassommEditFieldLabel.Visible = 'on';
                app.EntradasEditField_2.Visible = 'on';
                app.EntradasEditField_2Label.Visible = 'on';
            end
            
        end

        % Callback function
        function EntradasEditFieldValueChanged(app, event)
            value = app.EntradasEditField.Value;
        end

        % Callback function
        function RoscaDropDownValueChanged(app, event)
            value = app.RoscaDropDown.Value;
        end

        % Selection changed function: SistemasdeMedidasButtonGroup
        function SistemasdeMedidasButtonGroupSelectionChanged(app, event)
            selectedButton = app.SistemasdeMedidasButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, "Sistema Internacional") == 1
                app.units = 'SI';
                app.PadroDropDown.Items = {' ', 'M3', 'M3.5', 'M4', 'M5', 'M6', 'M7',...
                    'M8', 'M10', 'M13', 'M14', 'M16', 'M18', 'M20', 'M22', 'M24',...
                    'M27', 'M30', 'M33', 'M36', 'M39'};
                app.CargaNEditFieldLabel.Text = 'Carga [N]';
                app.VelocidademmsEditFieldLabel.Text = 'Velocidade [mm/s]';
                app.DimetroExternommEditFieldLabel.Text = 'Diâmetro Externo [mm]';
                app.PassommEditFieldLabel.Text = 'Passo [mm]';
                app.ResistnciatraoMPaEditFieldLabel.Text = 'Resistência à tração [MPa]';
                app.ResistnciaaoescoamentoMPaEditFieldLabel.Text = 'Resistência ao escoamento [MPa]';
                app.DimetrommEditFieldLabel.Text = 'Diâmetro [mm]';
                
            else
                app.units = 'IS';
                app.PadroDropDown.Items = {' ', '0', '1', '2', '3', '4', '5',...
                    '6', '8', '10', '12', '1/4', '5/16', '3/8', '7/16', '1/2',...
                    '9/16', '5/8' ,'3/4', '7/8'};
                app.CargaNEditFieldLabel.Text = 'Carga [lb]';
                app.VelocidademmsEditFieldLabel.Text = 'Velocidade [in/s]';
                app.DimetroExternommEditFieldLabel.Text = 'Diâmetro Externo [in]';
                app.PassommEditFieldLabel.Text = 'Passo [in]';
                app.ResistnciatraoMPaEditFieldLabel.Text = 'Resistência à tração [psi]';
                app.ResistnciaaoescoamentoMPaEditFieldLabel.Text = 'Resistência ao escoamento [psi]';
                app.DimetrommEditFieldLabel.Text = 'Diâmetro [in]';
            end
            
            if strcmp(app.units, "IS") == 1
                if strcmp(app.screw_type, "normalizado") == 1
                    app.PassoinEditFieldLabel.Visible = 'on';
                    app.PassoinEditField.Visible = 'on';
                else
                    app.PassoinEditFieldLabel.Visible = 'off';
                    app.PassoinEditField.Visible = 'off';
                end
            else
                app.PassoinEditFieldLabel.Visible = 'off';
                app.PassoinEditField.Visible = 'off';
            end
        end

        % Value changed function: PadroDropDown
        function PadroDropDownValueChanged(app, event)
            app.std_screw = app.PadroDropDown.Value;
            if strcmp(app.units, 'SI') == 1
                app.std_screw_vector = app.PADROES_SI(app.std_screw);
                
            elseif strcmp(app.units, 'IS') == 1
                app.std_screw_vector = app.PADROES_IS(app.std_screw);
            end
        end

        % Value changed function: RoscaDropDown
        function RoscaDropDownValueChanged2(app, event)
            app.rosca = app.RoscaDropDown.Value;
        end

        % Value changed function: EntradasEditField
        function EntradasEditFieldValueChanged2(app, event)
            app.entradas = app.EntradasEditField.Value;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            if strcmp(app.screw_type, 'normalizado') == 1 && strcmp(app.units, 'SI') == 1
                
                if sum(strcmp(app.std_screw, {'M3' 'M3.5' 'M4' 'M5' 'M6' 'M7'}))  == 1 && strcmp(app.rosca, 'Passo Fino') == 1
                    warndlg({['A configuração de rosca ' app.std_screw ' não permite passo fino.']; 'Troque para passo grosso.'},'Passo Incorreto');
                    return
                else
                    app.d_e = app.std_screw_vector(1);
                    app.passo = app.std_screw_vector(2);
                    if strcmp(app.rosca, 'Passo Fino') == 1
                        a_r = app.std_screw_vector(7);
                    elseif strcmp(app.rosca, 'Passo Grosso') == 1
                        a_r = app.std_screw_vector(4);
                    end
                    d_r = (4*a_r/pi);
                end
            elseif strcmp(app.screw_type, 'normalizado') == 1 && strcmp(app.units, 'IS') == 1
                app.d_e = app.std_screw_vector(1);
                if strcmp(app.rosca, 'Passo Fino') == 1
                    a_r = app.std_screw_vector(3);
                elseif strcmp(app.rosca, 'Passo Grosso') == 1
                    a_r = app.std_screw_vector(2);
                end
                d_r = (4*a_r/pi);
            else
                d_r = app.d_e - app.passo;
            end
            
            if strcmp(app.type_roca, 'Quadrada') == 1
                app.angulo_acme = 0;
            end
            
            l = app.entradas*app.passo;
            d_m = app.d_e - 0.5*app.passo;
            lamb = atan(l / (pi*d_m));
            a_trav = tan(lamb)*cos(deg2rad(app.angulo_acme));
            
            P_r1 = (app.carga*(app.coef_atrito*pi*d_m*sec(deg2rad(app.angulo_acme)) + l))/(pi*d_m - app.coef_atrito*l*sec(deg2rad(app.angulo_acme)));
            T_c = 0.5*app.carga*app.d_c*app.coef_atrito_colar;
            T_r1 = 0.5*d_m*P_r1 + T_c;
            T_0 = app.carga * l / (pi * 2);
            efi = 100*(T_0 / T_r1);
            
            freq = app.velocidade/l;
            omega = 2*pi*freq;
            
            Pot = T_r1*omega*1e-6;
            
            if tan(lamb)*cos(deg2rad(app.angulo_acme)) > app.coef_atrito
                app.travante = 1;
            else
                app.travante = 0;
            end


            %W_t: módulo de resistência à torção
            %Tensão devido a torção
            %shear stress
            W_t = (pi*d_r^3)/16;
            tau = T_r1/W_t;
            
            if tau >= app.sy/sqrt(3)
                app.criterio_tau = 1;
            else
                app.criterio_tau = 0;
            end
            
            % Tensão axial no corpo
            %axial_stress
            sigma = (-4*app.carga)/(pi*d_r^2);
            
            if abs(sigma) >= app.sut
                app.criterio_sigma = 1;
            else
                app.criterio_sigma = 0;
            end
            
            
            % Tensão superficial na rosca
            % Multiplica-se a carga por 0.38, considerando a carga no primeiro filete
            %sup_stress
            sigma_b = (-2*0.38*app.carga)/(pi*d_m*1*app.passo);
            
            if abs(sigma_b) >= app.sut
                app.criterio_sigma_b = 1;
            else
                app.criterio_sigma_b = 0;
            end
            
            if abs(sigma_b) >= app.sy/sqrt(3)
                app.criterio_cisalhamento_rosca = 1;
            else
                app.criterio_cisalhamento_rosca = 1;
            end
            
            % Tensão de flexão na rosca
            % Multiplica-se a carga por 0.38, considerando a carga no primeiro filete
            %bend_stress
            sigma_fle = (6*0.38*app.carga)/(pi*d_r*1*app.passo);
            
            if sigma_fle >= app.sy
                app.criterio_sigma_fle = 1;
            else
                app.criterio_sigma_fle = 0;
            end


            %Tensão transversal de cisalhamento
            % Multiplica-se a carga por 0.38, considerando a carga no primeiro filete
            %trans_shear
            tau_tran = (3*0.38*app.carga)/(pi*d_r*1*app.passo);
            
            % Critério de Von Mises
            %sigma_vm
            von_mises = (1 / sqrt(2)) * sqrt(sigma_fle^2 + sigma^2 + (sigma-sigma_fle)^2 + 6 * tau^2);


            %Critério 1 é falha
            %Critério 0 é não falha
            
            app.resultsApp = results(app,...
            app.criterio_tau,...
            app.criterio_sigma,... 
            app.criterio_sigma_b,...
            app.criterio_sigma_fle,... 
            app.criterio_cisalhamento_rosca,...
            app.units,...
            l,d_m,d_r,lamb,...
            Pot,efi,P_r1,T_r1,T_c,...
            tau, sigma, sigma_b, sigma_fle, tau_tran, von_mises,...
            app.travante);
            
            
        end

        % Value changed function: EntradasEditField_2
        function EntradasEditField_2ValueChanged(app, event)
            app.entradas = app.EntradasEditField_2.Value;
        end

        % Value changed function: PassommEditField
        function PassommEditFieldValueChanged(app, event)
            app.passo = app.PassommEditField.Value;
        end

        % Value changed function: DimetroExternommEditField
        function DimetroExternommEditFieldValueChanged(app, event)
            app.d_e = app.DimetroExternommEditField.Value;
        end

        % Value changed function: CargaNEditField
        function CargaNEditFieldValueChanged(app, event)
            app.carga = app.CargaNEditField.Value;
        end

        % Value changed function: VelocidademmsEditField
        function VelocidademmsEditFieldValueChanged(app, event)
            app.velocidade = app.VelocidademmsEditField.Value;
        end

        % Value changed function: CoefAtritoEditField
        function CoefAtritoEditFieldValueChanged(app, event)
            app.coef_atrito = app.CoefAtritoEditField.Value;
        end

        % Value changed function: ResistnciatraoMPaEditField
        function ResistnciatraoMPaEditFieldValueChanged(app, event)
            app.sut = app.ResistnciatraoMPaEditField.Value;
        end

        % Value changed function: ResistnciaaoescoamentoMPaEditField
        function ResistnciaaoescoamentoMPaEditFieldValueChanged(app, event)
            app.sy = app.ResistnciaaoescoamentoMPaEditField.Value;
        end

        % Value changed function: DimetrommEditField
        function DimetrommEditFieldValueChanged(app, event)
            app.d_c = app.DimetrommEditField.Value;
        end

        % Value changed function: CoefAtritoEditField_2
        function CoefAtritoEditField_2ValueChanged(app, event)
            app.coef_atrito_colar = app.CoefAtritoEditField_2.Value;
        end

        % Selection changed function: PerfilRoscaButtonGroup
        function PerfilRoscaButtonGroupSelectionChanged(app, event)
            selectedButton_perfil = app.PerfilRoscaButtonGroup.SelectedObject;
            
            if strcmp(selectedButton_perfil.Text, "Acme") == 1
                app.type_roca = 'Acme';
                app.nguloEditField.Visible = 'on';
                app.nguloEditFieldLabel.Visible = 'on';
            else
                app.type_roca = 'Quadrada';
                app.nguloEditField.Visible = 'off';
                app.nguloEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: INFOPERFISButton
        function INFOPERFISButtonPushed(app, event)
            URL = 'https://i.imgur.com/ExOgZYh.png';
            filename='test.png';
            urlwrite(URL,filename);
            [y,z]=imread(filename,'png');
            figure('Name','Tipos de Rosca','NumberTitle','off'), imshow(y);
            colormap(z)
        end

        % Value changed function: nguloEditField
        function nguloEditFieldValueChanged(app, event)
            app.angulo_acme = app.nguloEditField.Value;
        end

        % Value changed function: PassoinEditField
        function PassoinEditFieldValueChanged(app, event)
            app.passo = app.PassoinEditField.Value; % passo para o caso normalizado porém em IS
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DimensionamentoTab = uitab(app.TabGroup);
            app.DimensionamentoTab.Title = 'Dimensionamento';
            app.SistemasdeMedidasButtonGroup = uibuttongroup(app.DimensionamentoTab);
            app.SistemasdeMedidasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SistemasdeMedidasButtonGroupSelectionChanged, true);
            app.SistemasdeMedidasButtonGroup.Title = 'Sistemas de Medidas';
            app.SistemaInternacionalButton = uiradiobutton(app.SistemasdeMedidasButtonGroup);
            app.SistemaInternacionalButton.Tooltip = {'mm'; 'MPa'; 'N'; 'kW'; 'N.m'};
            app.SistemaInternacionalButton.Text = 'Sistema Internacional';
            app.SistemaInternacionalButton.Value = true;
            app.SistemaImperialButton = uiradiobutton(app.SistemasdeMedidasButtonGroup);
            app.SistemaImperialButton.Tooltip = {'in'; 'psi'; 'lb'; 'btu'; 'lb.in'};
            app.SistemaImperialButton.Text = 'Sistema Imperial';
            app.PropriedadesdomaterialPanel = uipanel(app.DimensionamentoTab);
            app.PropriedadesdomaterialPanel.Title = 'Propriedades do material';
            app.ResistnciaaoescoamentoMPaEditFieldLabel = uilabel(app.PropriedadesdomaterialPanel);
            app.ResistnciaaoescoamentoMPaEditFieldLabel.Text = 'Resistência ao escoamento [MPa]';
            app.ResistnciaaoescoamentoMPaEditField = uieditfield(app.PropriedadesdomaterialPanel, 'numeric');
            app.ResistnciaaoescoamentoMPaEditField.ValueChangedFcn = createCallbackFcn(app, @ResistnciaaoescoamentoMPaEditFieldValueChanged, true);
            app.ResistnciatraoMPaEditFieldLabel = uilabel(app.PropriedadesdomaterialPanel);
            app.ResistnciatraoMPaEditFieldLabel.Text = 'Resistência à tração [MPa]';
            app.ResistnciatraoMPaEditField = uieditfield(app.PropriedadesdomaterialPanel, 'numeric');
            app.ResistnciatraoMPaEditField.ValueChangedFcn = createCallbackFcn(app, @ResistnciatraoMPaEditFieldValueChanged, true);
            app.CoefAtritoEditFieldLabel = uilabel(app.PropriedadesdomaterialPanel);
            app.CoefAtritoEditFieldLabel.Text = 'Coef. Atrito';
            app.CoefAtritoEditField = uieditfield(app.PropriedadesdomaterialPanel, 'numeric');
            app.CoefAtritoEditField.ValueChangedFcn = createCallbackFcn(app, @CoefAtritoEditFieldValueChanged, true);
            app.ColarPanel = uipanel(app.DimensionamentoTab);
            app.ColarPanel.Title = 'Colar';
            app.DimetrommEditFieldLabel = uilabel(app.ColarPanel);
            app.DimetrommEditFieldLabel.Text = 'Diâmetro [mm]';
            app.DimetrommEditField = uieditfield(app.ColarPanel, 'numeric');
            app.DimetrommEditField.ValueChangedFcn = createCallbackFcn(app, @DimetrommEditFieldValueChanged, true);
            app.CoefAtritoEditField_2Label = uilabel(app.ColarPanel);
            app.CoefAtritoEditField_2Label.Text = 'Coef. Atrito';
            app.CoefAtritoEditField_2 = uieditfield(app.ColarPanel, 'numeric');
            app.CoefAtritoEditField_2.ValueChangedFcn = createCallbackFcn(app, @CoefAtritoEditField_2ValueChanged, true);
            app.CargaseDinmicaPanel = uipanel(app.DimensionamentoTab);
            app.CargaseDinmicaPanel.Title = 'Cargas e Dinâmica';
            app.CargaNEditFieldLabel = uilabel(app.CargaseDinmicaPanel);
            app.CargaNEditFieldLabel.Text = 'Carga [N]';
            app.CargaNEditField = uieditfield(app.CargaseDinmicaPanel, 'numeric');
            app.CargaNEditField.ValueChangedFcn = createCallbackFcn(app, @CargaNEditFieldValueChanged, true);
            app.VelocidademmsEditFieldLabel = uilabel(app.CargaseDinmicaPanel);
            app.VelocidademmsEditFieldLabel.Text = 'Velocidade [mm/s]';
            app.VelocidademmsEditField = uieditfield(app.CargaseDinmicaPanel, 'numeric');
            app.VelocidademmsEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidademmsEditFieldValueChanged, true);
            app.PerfilRoscaButtonGroup = uibuttongroup(app.DimensionamentoTab);
            app.PerfilRoscaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PerfilRoscaButtonGroupSelectionChanged, true);
            app.PerfilRoscaButtonGroup.Title = 'Perfil Rosca';
            app.QuadradaButton = uiradiobutton(app.PerfilRoscaButtonGroup);
            app.QuadradaButton.Text = 'Quadrada';
            app.QuadradaButton.Value = true;
            app.AcmeButton = uiradiobutton(app.PerfilRoscaButtonGroup);
            app.AcmeButton.Text = 'Acme';
            app.nguloEditFieldLabel = uilabel(app.PerfilRoscaButtonGroup);
            app.nguloEditFieldLabel.Visible = 'off';
            app.nguloEditFieldLabel.Text = 'Ângulo [º]';
            app.nguloEditField = uieditfield(app.PerfilRoscaButtonGroup, 'numeric');
            app.nguloEditField.ValueChangedFcn = createCallbackFcn(app, @nguloEditFieldValueChanged, true);
            app.nguloEditField.Visible = 'off';
            app.INFOPERFISButton = uibutton(app.PerfilRoscaButtonGroup, 'push');
            app.INFOPERFISButton.ButtonPushedFcn = createCallbackFcn(app, @INFOPERFISButtonPushed, true);
            app.INFOPERFISButton.Text = 'INFO PERFIS';
            app.TipodeParafusoPanel = uipanel(app.DimensionamentoTab);
            app.TipodeParafusoPanel.Title = 'Tipo de Parafuso';
            app.ButtonGroup = uibuttongroup(app.TipodeParafusoPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NormalizadoButton = uiradiobutton(app.ButtonGroup);
            app.NormalizadoButton.Text = 'Normalizado';
            app.NormalizadoButton.Value = true;
            app.PersonalizadoButton = uiradiobutton(app.ButtonGroup);
            app.PersonalizadoButton.Text = 'Personalizado';
            app.RoscaDropDownLabel = uilabel(app.TipodeParafusoPanel);
            app.RoscaDropDownLabel.Text = 'Rosca';
            app.RoscaDropDown = uidropdown(app.TipodeParafusoPanel);
            app.RoscaDropDown.Items = {'', 'Passo Fino', 'Passo Grosso'};
            app.RoscaDropDown.Editable = 'on';
            app.RoscaDropDown.ValueChangedFcn = createCallbackFcn(app, @RoscaDropDownValueChanged2, true);
            app.RoscaDropDown.Value = '';
            app.EntradasEditFieldLabel = uilabel(app.TipodeParafusoPanel);
            app.EntradasEditFieldLabel.Text = 'Entradas';
            app.EntradasEditField = uieditfield(app.TipodeParafusoPanel, 'numeric');
            app.EntradasEditField.ValueChangedFcn = createCallbackFcn(app, @EntradasEditFieldValueChanged2, true);
            app.PadroDropDownLabel = uilabel(app.TipodeParafusoPanel);
            app.PadroDropDownLabel.Text = 'Padrão';
            app.PadroDropDown = uidropdown(app.TipodeParafusoPanel);
            app.PadroDropDown.Items = {'', 'M3', 'M3.5', 'M4', 'M5', 'M6', 'M7', 'M8', 'M10', 'M13', 'M14', 'M16', 'M18', 'M20', 'M22', 'M24', 'M27', 'M30', 'M33', 'M36', 'M39', ''};
            app.PadroDropDown.Editable = 'on';
            app.PadroDropDown.ValueChangedFcn = createCallbackFcn(app, @PadroDropDownValueChanged, true);
            app.PadroDropDown.Value = '';
            app.EntradasEditField_2Label = uilabel(app.TipodeParafusoPanel);
            app.EntradasEditField_2Label.Visible = 'off';
            app.EntradasEditField_2Label.Text = 'Entradas';
            app.EntradasEditField_2 = uieditfield(app.TipodeParafusoPanel, 'numeric');
            app.EntradasEditField_2.ValueChangedFcn = createCallbackFcn(app, @EntradasEditField_2ValueChanged, true);
            app.EntradasEditField_2.Visible = 'off';
            app.PassommEditFieldLabel = uilabel(app.TipodeParafusoPanel);
            app.PassommEditFieldLabel.Visible = 'off';
            app.PassommEditFieldLabel.Text = 'Passo [mm]';
            app.PassommEditField = uieditfield(app.TipodeParafusoPanel, 'numeric');
            app.PassommEditField.ValueChangedFcn = createCallbackFcn(app, @PassommEditFieldValueChanged, true);
            app.PassommEditField.Visible = 'off';
            app.PassommEditField.Tooltip = {' '};
            app.DimetroExternommEditFieldLabel = uilabel(app.TipodeParafusoPanel);
            app.DimetroExternommEditFieldLabel.Visible = 'off';
            app.DimetroExternommEditFieldLabel.Text = 'Diâmetro Externo [mm]';
            app.DimetroExternommEditField = uieditfield(app.TipodeParafusoPanel, 'numeric');
            app.DimetroExternommEditField.ValueChangedFcn = createCallbackFcn(app, @DimetroExternommEditFieldValueChanged, true);
            app.DimetroExternommEditField.Visible = 'off';
            app.DimetroExternommEditField.Tooltip = {'mm'};
            app.PassoinEditFieldLabel = uilabel(app.TipodeParafusoPanel);
            app.PassoinEditFieldLabel.Visible = 'off';
            app.PassoinEditFieldLabel.Text = 'Passo [in]';
            app.PassoinEditField = uieditfield(app.TipodeParafusoPanel, 'numeric');
            app.PassoinEditField.ValueChangedFcn = createCallbackFcn(app, @PassoinEditFieldValueChanged, true);
            app.PassoinEditField.Visible = 'off';
            app.CalcularButton = uibutton(app.DimensionamentoTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.CrditosButton = uibutton(app.DimensionamentoTab, 'push');
            app.CrditosButton.ButtonPushedFcn = createCallbackFcn(app, @CrditosButtonPushed, true);
            app.CrditosButton.Text = 'Créditos';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end