classdef results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        ResultadosTab                   matlab.ui.container.Tab
        ConfiguraoGeomtricaPanel        matlab.ui.container.Panel
        AvanommEditFieldLabel           matlab.ui.control.Label
        AvanommEditField                matlab.ui.control.NumericEditField
        DimetromdiommEditFieldLabel     matlab.ui.control.Label
        DimetromdiommEditField          matlab.ui.control.NumericEditField
        DimetroraizmmEditFieldLabel     matlab.ui.control.Label
        DimetroraizmmEditField          matlab.ui.control.NumericEditField
        gulodeavanoLabel                matlab.ui.control.Label
        gulodeavanoEditField            matlab.ui.control.NumericEditField
        CargasDinmicaPanel              matlab.ui.container.Panel
        PotnciakWEditFieldLabel         matlab.ui.control.Label
        PotnciakWEditField              matlab.ui.control.NumericEditField
        EficinciaEditFieldLabel         matlab.ui.control.Label
        EficinciaEditField              matlab.ui.control.NumericEditField
        CargaelevaoNEditFieldLabel      matlab.ui.control.Label
        CargaelevaoNEditField           matlab.ui.control.NumericEditField
        TorqueelevaoNmEditFieldLabel    matlab.ui.control.Label
        TorqueelevaoNmEditField         matlab.ui.control.NumericEditField
        TorquecolarNmEditFieldLabel     matlab.ui.control.Label
        TorquecolarNmEditField          matlab.ui.control.NumericEditField
        FalhasPanel                     matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ParafusoTab_2                   matlab.ui.container.Tab
        TextAreaParafuso                matlab.ui.control.TextArea
        RoscaTab_2                      matlab.ui.container.Tab
        TextAreaRosca                   matlab.ui.control.TextArea
        TensesrelevantesPanel           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        RoscaTab                        matlab.ui.container.Tab
        TensoVonMisesMPaEditFieldLabel  matlab.ui.control.Label
        TensoVonMisesMPaEditField       matlab.ui.control.NumericEditField
        TensoCisalhamentoMPaEditFieldLabel  matlab.ui.control.Label
        TensoCisalhamentoMPaEditField   matlab.ui.control.NumericEditField
        TensoFlexoMPaEditFieldLabel     matlab.ui.control.Label
        TensoFlexoMPaEditField          matlab.ui.control.NumericEditField
        TensoApoioMPaEditFieldLabel     matlab.ui.control.Label
        TensoApoioMPaEditField          matlab.ui.control.NumericEditField
        ParafusoTab                     matlab.ui.container.Tab
        TensoCisalhamentoMPaEditField_2Label  matlab.ui.control.Label
        TensoCisalhamentoMPaEditField_2  matlab.ui.control.NumericEditField
        TensoAxialMPaEditFieldLabel     matlab.ui.control.Label
        TensoAxialMPaEditField          matlab.ui.control.NumericEditField
        SistemaautotravanteLabel        matlab.ui.control.Label
        FecharButton                    matlab.ui.control.Button
        DesignTab                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        CallingApp % Description
        criterio_tau_re
        criterio_sigma_re
        criterio_sigma_b_re
        criterio_sigma_fle_re
        criterio_cisalhamento_rosca_re
        
        units % SI ou IS
        travante % se o sistema é autotravado ou não
        
        l
        d_m
        d_r
        lamb
        
        Pot
        efi
        Pr_1
        T_r1
        T_c
        
        %Parafuso
        tau
        sigma
        
        %Rosca
        sigma_b
        sigma_fle
        tau_tran
        von_mises
        
        criterios_cell_rosca = {};
        criterios_cell_parafuso ={};


    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, criterio_tau, criterio_sigma, criterio_sigma_b, criterio_sigma_fle, criterio_cisalhamento_rosca, units, l, d_m, d_r, lamb, Pot, efi, P_r1, T_r1, T_c, tau, sigma, sigma_b, sigma_fle, tau_tran, von_mises, travante)
            app.CallingApp = mainapp;
            
            app.criterio_tau_re = criterio_tau;
            app.criterio_sigma_b_re = criterio_sigma_b;
            app.criterio_sigma_re = criterio_sigma;
            app.criterio_cisalhamento_rosca_re = criterio_cisalhamento_rosca;
            app.criterio_sigma_fle_re = criterio_sigma_fle;
            
            app.units = units;
            app.travante = travante;
            
            app.l = l;
            app.d_m = d_m;
            app.d_r = d_r;
            app.lamb = rad2deg(lamb);
            
            app.Pot = Pot;
            app.efi = efi;
            app.Pr_1 = P_r1;
            app.T_r1 = T_r1;
            app.T_c = T_c;
            
            app.sigma = sigma;
            app.tau = tau;
            app.sigma_b = sigma_b;
            app.sigma_fle = sigma_fle;
            app.tau_tran = tau_tran;
            app.von_mises = von_mises;
            
            if strcmp(app.units, "IS") == 1
                app.AvanommEditFieldLabel.Text = 'Avanço [in]';
                app.DimetromdiommEditFieldLabel.Text = 'Diâmetro médio [in]';
                app.DimetroraizmmEditFieldLabel.Text = 'Diâmetro raiz [in]';
                app.PotnciakWEditFieldLabel.Text = 'Potência [btu]';
                app.CargaelevaoNEditFieldLabel.Text = 'Carga elevação [lb]';
                app.TorqueelevaoNmEditFieldLabel.Text = 'Torque elevação [lb.in]';
                app.TorquecolarNmEditFieldLabel.Text = 'Torque colar [lb.in]';
                
                app.TensoAxialMPaEditFieldLabel.Text = 'Tensão Axial [psi]';
                app.TensoCisalhamentoMPaEditField_2Label.Text = 'Tensão Cisalhamento [psi]';
                app.TensoApoioMPaEditFieldLabel.Text = 'Tensão Apoio [psi]';
                app.TensoFlexoMPaEditFieldLabel.Text = 'Tensão Flexão [psi]';
                app.TensoCisalhamentoMPaEditFieldLabel.Text = 'Tensão Cisalhamento [psi]';
                app.TensoVonMisesMPaEditFieldLabel.Text = 'Tensão Von-Mises [psi]';
                
                
            end
            
            if app.criterio_tau_re == 1
                app.criterios_cell_parafuso{end+1} = '-> Ocorre falha por cisalhamento no parafuso';
            else
                app.criterios_cell_parafuso{end+1} = '-> Não ocorre falha por cisalhamento no parafuso';
            end
            
            if app.criterio_sigma_re == 1
                app.criterios_cell_parafuso{end+1} = '-> Ocorre falha por compressão no parafuso';
            else
                app.criterios_cell_parafuso{end+1} = '-> Não ocorre falha por compressão no parafuso';
            end


            if app.criterio_cisalhamento_rosca_re == 1
                app.criterios_cell_rosca{end+1} = '-> Ocorre falha por cisalhamento na rosca';
            else
                app.criterios_cell_rosca{end+1} = '-> Não ocorre falha por cisalhamento na rosca';
            end
            
            if app.criterio_sigma_b_re == 1
                app.criterios_cell_rosca{end+1} = '-> Ocorre falha por compressão na rosca';
            else
                app.criterios_cell_rosca{end+1} = '-> Não ocorre falha por compressão na rosca';
            end


            if app.criterio_sigma_fle_re == 1
                app.criterios_cell_rosca{end+1} = '-> Ocorre falha por flexão na rosca';
            else
                app.criterios_cell_rosca{end+1} = '-> Não ocorre falha por cisalhamento na rosca';
            end
            
            
            if app.travante == 1
                app.SistemaautotravanteLabel.Text = 'Sistema auto-travante';
            else
                app.SistemaautotravanteLabel.Text = '';
            end


            app.TorquecolarNmEditField.Value = app.T_c;
            app.TorqueelevaoNmEditField.Value = app.T_r1;
            app.CargaelevaoNEditField.Value = app.Pr_1;
            app.EficinciaEditField.Value = app.efi;
            app.PotnciakWEditField.Value = app.Pot;
            app.gulodeavanoEditField.Value = app.lamb;
            app.DimetroraizmmEditField.Value = app.d_r;
            app.DimetromdiommEditField.Value = app.d_m;
            app.AvanommEditField.Value = app.l;
            
            app.TensoAxialMPaEditField.Value = app.sigma;
            app.TensoCisalhamentoMPaEditField_2.Value = app.tau;
            app.TensoApoioMPaEditField.Value = app.sigma_b;
            app.TensoFlexoMPaEditField.Value = app.sigma_fle;
            app.TensoCisalhamentoMPaEditField.Value = app.tau_tran;
            app.TensoVonMisesMPaEditField.Value = app.von_mises;
            
                       
            app.TextAreaRosca.Value = app.criterios_cell_rosca;
            app.TextAreaParafuso.Value = app.criterios_cell_parafuso;
            
        end

        % Button pushed function: FecharButton
        function FecharButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.ResultadosTab = uitab(app.TabGroup3);
            app.ResultadosTab.Title = 'Resultados ';
            app.ConfiguraoGeomtricaPanel = uipanel(app.ResultadosTab);
            app.ConfiguraoGeomtricaPanel.Title = 'Configuração Geométrica';
            app.AvanommEditFieldLabel = uilabel(app.ConfiguraoGeomtricaPanel);
            app.AvanommEditFieldLabel.Text = 'Avanço [mm]';
            app.AvanommEditField = uieditfield(app.ConfiguraoGeomtricaPanel, 'numeric');
            app.AvanommEditField.Editable = 'off';
            app.AvanommEditField.Value = 1;
            app.DimetromdiommEditFieldLabel = uilabel(app.ConfiguraoGeomtricaPanel);
            app.DimetromdiommEditFieldLabel.Text = 'Diâmetro médio [mm]';
            app.DimetromdiommEditField = uieditfield(app.ConfiguraoGeomtricaPanel, 'numeric');
            app.DimetromdiommEditField.Editable = 'off';
            app.DimetromdiommEditField.Value = 1;
            app.DimetroraizmmEditFieldLabel = uilabel(app.ConfiguraoGeomtricaPanel);
            app.DimetroraizmmEditFieldLabel.Text = 'Diâmetro raiz [mm]';
            app.DimetroraizmmEditField = uieditfield(app.ConfiguraoGeomtricaPanel, 'numeric');
            app.DimetroraizmmEditField.Editable = 'off';
            app.DimetroraizmmEditField.Value = 1;
            app.gulodeavanoLabel = uilabel(app.ConfiguraoGeomtricaPanel);
            app.gulodeavanoLabel.Text = 'Âgulo de avanço [º]';
            app.gulodeavanoEditField = uieditfield(app.ConfiguraoGeomtricaPanel, 'numeric');
            app.gulodeavanoEditField.Editable = 'off';
            app.gulodeavanoEditField.Value = 1;
            app.CargasDinmicaPanel = uipanel(app.ResultadosTab);
            app.CargasDinmicaPanel.Title = 'Cargas & Dinâmica';
            app.PotnciakWEditFieldLabel = uilabel(app.CargasDinmicaPanel);
            app.PotnciakWEditFieldLabel.Text = 'Potência [kW]';
            app.PotnciakWEditField = uieditfield(app.CargasDinmicaPanel, 'numeric');
            app.PotnciakWEditField.Editable = 'off';
            app.PotnciakWEditField.Value = 1;
            app.EficinciaEditFieldLabel = uilabel(app.CargasDinmicaPanel);
            app.EficinciaEditFieldLabel.Text = 'Eficiência [%]';
            app.EficinciaEditField = uieditfield(app.CargasDinmicaPanel, 'numeric');
            app.EficinciaEditField.Editable = 'off';
            app.EficinciaEditField.Value = 1;
            app.CargaelevaoNEditFieldLabel = uilabel(app.CargasDinmicaPanel);
            app.CargaelevaoNEditFieldLabel.Text = 'Carga elevação [N]';
            app.CargaelevaoNEditField = uieditfield(app.CargasDinmicaPanel, 'numeric');
            app.CargaelevaoNEditField.Editable = 'off';
            app.CargaelevaoNEditField.Value = 1;
            app.TorqueelevaoNmEditFieldLabel = uilabel(app.CargasDinmicaPanel);
            app.TorqueelevaoNmEditFieldLabel.Text = 'Torque elevação [N.m]';
            app.TorqueelevaoNmEditField = uieditfield(app.CargasDinmicaPanel, 'numeric');
            app.TorqueelevaoNmEditField.Editable = 'off';
            app.TorqueelevaoNmEditField.Value = 1;
            app.TorquecolarNmEditFieldLabel = uilabel(app.CargasDinmicaPanel);
            app.TorquecolarNmEditFieldLabel.Text = 'Torque colar [N.m]';
            app.TorquecolarNmEditField = uieditfield(app.CargasDinmicaPanel, 'numeric');
            app.TorquecolarNmEditField.Editable = 'off';
            app.TorquecolarNmEditField.Value = 1;
            app.FalhasPanel = uipanel(app.ResultadosTab);
            app.FalhasPanel.Title = 'Falhas';
            app.TabGroup2 = uitabgroup(app.FalhasPanel);
            app.ParafusoTab_2 = uitab(app.TabGroup2);
            app.ParafusoTab_2.Title = 'Parafuso';
            app.TextAreaParafuso = uitextarea(app.ParafusoTab_2);
            app.TextAreaParafuso.Value = {'FALHAS PARAFUSO'};
            app.RoscaTab_2 = uitab(app.TabGroup2);
            app.RoscaTab_2.Title = 'Rosca';
            app.TextAreaRosca = uitextarea(app.RoscaTab_2);
            app.TextAreaRosca.Value = {'FALHAS ROSCA'};
            app.TensesrelevantesPanel = uipanel(app.ResultadosTab);
            app.TensesrelevantesPanel.Title = 'Tensões relevantes';
            app.TabGroup = uitabgroup(app.TensesrelevantesPanel);
            app.RoscaTab = uitab(app.TabGroup);
            app.RoscaTab.Title = 'Rosca';
            app.TensoVonMisesMPaEditFieldLabel = uilabel(app.RoscaTab);
            app.TensoVonMisesMPaEditFieldLabel.Text = 'Tensão Von-Mises [MPa]';
            app.TensoVonMisesMPaEditField = uieditfield(app.RoscaTab, 'numeric');
            app.TensoVonMisesMPaEditField.Editable = 'off';
            app.TensoVonMisesMPaEditField.Value = 1;
            app.TensoCisalhamentoMPaEditFieldLabel = uilabel(app.RoscaTab);
            app.TensoCisalhamentoMPaEditFieldLabel.Text = 'Tensão Cisalhamento [MPa]';
            app.TensoCisalhamentoMPaEditField = uieditfield(app.RoscaTab, 'numeric');
            app.TensoCisalhamentoMPaEditField.Editable = 'off';
            app.TensoCisalhamentoMPaEditField.Value = 1;
            app.TensoFlexoMPaEditFieldLabel = uilabel(app.RoscaTab);
            app.TensoFlexoMPaEditFieldLabel.Text = 'Tensão Flexão [MPa]';
            app.TensoFlexoMPaEditField = uieditfield(app.RoscaTab, 'numeric');
            app.TensoFlexoMPaEditField.Editable = 'off';
            app.TensoFlexoMPaEditField.Value = 1;
            app.TensoApoioMPaEditFieldLabel = uilabel(app.RoscaTab);
            app.TensoApoioMPaEditFieldLabel.Text = 'Tensão Apoio [MPa]';
            app.TensoApoioMPaEditField = uieditfield(app.RoscaTab, 'numeric');
            app.TensoApoioMPaEditField.Editable = 'off';
            app.TensoApoioMPaEditField.Value = 1;
            app.ParafusoTab = uitab(app.TabGroup);
            app.ParafusoTab.Title = 'Parafuso';
            app.TensoCisalhamentoMPaEditField_2Label = uilabel(app.ParafusoTab);
            app.TensoCisalhamentoMPaEditField_2Label.Text = 'Tensão Cisalhamento [MPa]';
            app.TensoCisalhamentoMPaEditField_2 = uieditfield(app.ParafusoTab, 'numeric');
            app.TensoCisalhamentoMPaEditField_2.Editable = 'off';
            app.TensoCisalhamentoMPaEditField_2.Value = 1;
            app.TensoAxialMPaEditFieldLabel = uilabel(app.ParafusoTab);
            app.TensoAxialMPaEditFieldLabel.Text = 'Tensão Axial [MPa]';
            app.TensoAxialMPaEditField = uieditfield(app.ParafusoTab, 'numeric');
            app.TensoAxialMPaEditField.Editable = 'off';
            app.TensoAxialMPaEditField.Value = 1;
            app.SistemaautotravanteLabel = uilabel(app.ResultadosTab);
            app.SistemaautotravanteLabel.Text = 'Sistema auto-travante';
            app.FecharButton = uibutton(app.ResultadosTab, 'push');
            app.FecharButton.ButtonPushedFcn = createCallbackFcn(app, @FecharButtonPushed, true);
            app.FecharButton.Text = 'Fechar';
            app.DesignTab = uitab(app.TabGroup3);
            app.DesignTab.Title = 'Design';
        end
    end

    methods (Access = public)

        % Construct app
        function app = results(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end